classdef EventCameraArrayGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StatusLamp                     matlab.ui.control.Lamp
        StatusLampLabel                matlab.ui.control.Label
        informationboardTextArea       matlab.ui.control.TextArea
        informationboardTextAreaLabel  matlab.ui.control.Label
        VisualizeButton                matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        ProcessButton                  matlab.ui.control.Button
        ResizeHeightpixelEditField     matlab.ui.control.EditField
        VideoHeightLabel               matlab.ui.control.Label
        ResizeWidthpixelEditField      matlab.ui.control.EditField
        VideoWidthLabel                matlab.ui.control.Label
        RefractoryTimes010EditField    matlab.ui.control.EditField
        RefractoryTimes010EditFieldLabel  matlab.ui.control.Label
        customizedaccumulationtimesec0001sEditField  matlab.ui.control.EditField
        Step4AccumulationTimeLabel     matlab.ui.control.Label
        TriggeringthresholdC0105EditField  matlab.ui.control.EditField
        Step3CLabel                    matlab.ui.control.Label
        VideoPathEditField             matlab.ui.control.EditField
        VideoPathLabel                 matlab.ui.control.Label
        ChooseVideoButton              matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        eventData               struct
        videoInfo               struct
        isProcessed             logical = false
        progressData            struct
        % 视频显示相关属性
        VideoAxes              matlab.ui.control.UIAxes
        VideoFrameImage        matlab.graphics.primitive.Image
        IsProcessing           logical = false
        CurrentFrame           uint8
        displayMode            char = 'events'

        VideoData      struct   % load_x4k_frames 的输出（给 overlay 用）
        SortedEvents   struct   % 排序后的事件
        SeqPath        char     % 视频路径（用于 overlay 的 VideoReader）
        Stats          struct   % 统计信息（可选）
    end

    methods (Access = private)

        function initializeVideoDisplay(app, Y)
            % 初始化视频显示
            try
                % 检查UIAxes是否存在且有效
                if isempty(app.UIAxes) || ~isvalid(app.UIAxes)
                    app.addLog('INFO: UIAxes not available, skipping video display');
                    return;
                end

                % 清除之前的显示
                cla(app.UIAxes);

                % 显示第一帧
                if ndims(Y) == 3 && size(Y,3) >= 1
                    firstFrame = Y(:,:,1);
                else
                    firstFrame = Y;
                end

                % 创建图像显示
                imagesc(app.UIAxes, firstFrame);
                % 智能选择colormap
                if ismatrix(firstFrame) % 灰度图像
                    colormap(app.UIAxes, 'gray');
                    app.addLog('Displaying grayscale video');
                else % 彩色图像
                    colormap(app.UIAxes, 'default');
                    app.addLog('Displaying color video');
                end

                axis(app.UIAxes, 'image');

                % 设置标题和标签
                if ndims(Y) == 3
                    title(app.UIAxes, sprintf('Input Video - Frame 1/%d', size(Y,3)));
                else
                    title(app.UIAxes, 'Input Video Frame');
                end

                app.UIAxes.XLabel.String = 'Width (pixels)';
                app.UIAxes.YLabel.String = 'Height (pixels)';

                % 添加colorbar
                colorbar(app.UIAxes);

                % 刷新显示
                drawnow;

                app.addLog('Video display initialized');

            catch ME
                app.addLog(['WARNING: Could not initialize video display: ' ME.message]);
            end
        end

        function updateVideoFrame(app, frame, titleText)
            % 更新视频帧显示
            try
                if isempty(app.UIAxes) || ~isvalid(app.UIAxes)
                    return;
                end

                imagesc(app.UIAxes, frame);
                title(app.UIAxes, titleText);
                colormap(app.UIAxes, 'gray');
                axis(app.UIAxes, 'image');

                drawnow;

            catch ME
                app.addLog(['WARNING: Could not update video frame: ' ME.message]);
            end
        end

        function updateVideoProgress(app)
            % 定时器回调函数，更新视频进度
            try
                if ~app.IsProcessing || ~isvalid(app.UIAxes) || ~isfield(app, 'progressData')
                    return;
                end

                % 获取当前进度
                currentFrame = app.progressData.currentFrame;
                totalFrames = app.progressData.totalFrames;
                Y = app.progressData.Y;

                % 基于时间自动播放视频
                elapsedTime = toc(app.progressData.startTime);

                % 计算应该显示的帧（模拟视频播放）
                % 假设处理过程中视频以正常速度播放
                videoFPS = 10; % 播放速度：10帧/秒
                targetFrame = min(totalFrames, max(1, round(elapsedTime * videoFPS)));

                % 如果到了该更新帧的时间
                if targetFrame > currentFrame || toc(app.progressData.lastFrameUpdate) >= app.progressData.frameInterval
                    % 更新视频帧
                    frame = Y(:,:,targetFrame);

                    % 更新显示
                    if isvalid(app.UIAxes)
                        imagesc(app.UIAxes, frame);
                        title(app.UIAxes, sprintf('Processing... Frame %d/%d (Elapsed: %.1fs)', ...
                            targetFrame, totalFrames, elapsedTime));
                        colormap(app.UIAxes, 'gray');
                        axis(app.UIAxes, 'image');
                        drawnow limitrate;
                    end

                    % 保存当前帧进度
                    app.progressData.currentFrame = targetFrame;
                    app.progressData.lastFrameUpdate = tic;
                end

            catch ME
                % 忽略显示更新中的错误，不影响主要处理
            end
        end

        function autoFillVideoDimensions(app, videoPath)
            % 自动获取视频尺寸并填充到宽度和高度字段
            try
                % 使用VideoReader获取视频信息
                videoObj = VideoReader(videoPath);

                % 获取视频尺寸
                width = videoObj.Width;
                height = videoObj.Height;

                app.ResizeWidthpixelEditField.Value = num2str(width);
                app.ResizeHeightpixelEditField.Value = num2str(height);

                app.addLog(sprintf('Video dimensions: %dx%d', width, height));
                app.addLog('Using original video resolution (no scaling)');

            catch ME
                % 如果VideoReader失败，使用默认值并显示警告
                app.addLog('WARNING: Could not read video dimensions, using defaults');
                app.addLog(['Error: ' ME.message]);

                % 设置默认值
                app.ResizeWidthpixelEditField.Value = '1024';
                app.ResizeHeightpixelEditField.Value = '540';
            end
        end

        function addLog(app, message)

            timestamp = datestr(now, 'HH:MM:SS');
            logMessage = sprintf('[%s] %s', timestamp, message);

            currentLog = app.informationboardTextArea.Value;
            if isempty(currentLog)
                newLog = {logMessage};
            else
                newLog = [currentLog; logMessage];
            end


            if length(newLog) > 50
                newLog = newLog(end-49:end);
            end

            app.informationboardTextArea.Value = newLog;
            drawnow;
        end

        function normalized_image = normalizeEventImage(app, event_accumulation)
            % 归一化事件累积图像用于显示
            try
                % 分离RGB通道
                red_channel = event_accumulation(:,:,1);   % ON事件
                blue_channel = event_accumulation(:,:,3);  % OFF事件

                % 分别归一化每个通道
                if max(red_channel(:)) > 0
                    red_channel = red_channel / max(red_channel(:));
                end
                if max(blue_channel(:)) > 0
                    blue_channel = blue_channel / max(blue_channel(:));
                end

                % 创建RGB图像：红色=ON事件，蓝色=OFF事件，绿色=两者重叠
                green_channel = min(red_channel, blue_channel); % 重叠区域显示为绿色

                normalized_image = cat(3, red_channel, green_channel, blue_channel);

                % 应用gamma校正增强视觉效果
                normalized_image = normalized_image .^ 0.7;

            catch
                % 如果处理失败，返回原始图像
                normalized_image = event_accumulation;
            end
        end

        function displayFinalEvents(app, event_accumulation, all_events, H, W)
            % 显示最终事件处理结果
            try
                if isempty(app.UIAxes) || ~isvalid(app.UIAxes)
                    return;
                end

                % 归一化事件图像
                final_display = app.normalizeEventImage(event_accumulation);

                % 显示最终结果
                imagesc(app.UIAxes, final_display);
                title(app.UIAxes, sprintf('Event Camera Output - %d events generated', length(all_events.t)));

                % 添加图例说明
                hold(app.UIAxes, 'on');

                % 创建自定义colorbar或图例
                app.UIAxes.XLabel.String = 'Width (pixels)';
                app.UIAxes.YLabel.String = 'Height (pixels)';

                % 添加文本说明
                text(app.UIAxes, W*0.02, H*0.85, 'Red: ON Events', ...
                    'Color', 'red', 'FontSize', 10, 'FontWeight', 'bold', ...
                    'BackgroundColor', 'white');
                text(app.UIAxes, W*0.02, H*0.5, 'Blue: OFF Events', ...
                    'Color', 'blue', 'FontSize', 10, 'FontWeight', 'bold', ...
                    'BackgroundColor', 'white');
                text(app.UIAxes, W*0.02, H*0.15, 'Green: Overlap', ...
                    'Color', 'green', 'FontSize', 10, 'FontWeight', 'bold', ...
                    'BackgroundColor', 'white');

                hold(app.UIAxes, 'off');

                axis(app.UIAxes, 'image');
                colorbar(app.UIAxes);

                drawnow;

                app.addLog('Final event display updated');

            catch ME
                app.addLog(['WARNING: Could not display final events: ' ME.message]);
            end
        end

        function processVideo(app, videoPath, outputPath, varargin)
            % PROCESSVIDEO 处理视频并生成事件数据

                % ------- 解析 Name-Value 参数 -------
            p = inputParser;
            p.addParameter('C',                 0.5,    @(x)isnumeric(x)&&isscalar(x)&&x>0);
            p.addParameter('accumTime',         0.01,   @(x)isnumeric(x)&&isscalar(x)&&x>0);
            p.addParameter('refractory_us',     1,      @(x)isnumeric(x)&&isscalar(x)&&x>=0);
            p.addParameter('resizeTo',          [540 1024], @(x)isnumeric(x)&&numel(x)==2);
            p.addParameter('fps',               1000,   @(x)isnumeric(x)&&isscalar(x)&&x>0);
            p.addParameter('showVisualization', false,  @(x)islogical(x)||ismember(x,[0 1]));
            p.parse(varargin{:});
        
            C                 = p.Results.C;
            accumTime         = p.Results.accumTime;
            refractory_us     = p.Results.refractory_us;
            resizeTo          = p.Results.resizeTo;
            fps               = p.Results.fps;
            showVisualization = logical(p.Results.showVisualization);
        
            % ------- 组装 opts 给 run_full_resolution_demo_fixed -------
            opts = struct();
            opts.Lref0                 = [];
            opts.refractory_us         = refractory_us;
            opts.threshold_jitter      = 0;
            opts.threshold_sigma_px    = 0;
            opts.pr = struct('enable', true, 'tau_dark_us', 5000, 'tau_bright_us', 500);
            opts.leak = struct('enable', false);
            opts.timing_jitter = struct('enable', false);
        
            % ------- 调用计算主流程（不在此处绘图） -------
            try
                % 返回：video_data（给 overlay）、sorted_events（给 overlay/summary）、stats（可显示）
                [video_data, sorted_events, stats] = run_full_resolution_demo_fixed( ...
                    videoPath, fps, resizeTo, C, opts);
        
                % 缓存到 app 属性，方便其它按钮/回调复用
                app.VideoData    = video_data;
                app.SortedEvents = sorted_events;
                app.SeqPath      = videoPath;
                app.Stats        = stats;
        
                % ------- 结果保存（与原脚本的保存语义对齐一些） -------
                if ~isempty(outputPath)
                    try
                        save([outputPath '.mat'], 'sorted_events', 'video_data', 'stats', 'C', 'opts', '-v7.3');
                    catch ME
                        app.addLog(['Save mat file ERROR: ' ME.message]);
                    end
                end
        
                % ------- 可选：在 UIAxes 里可视化 overlay -------
                if showVisualization && ~isempty(sorted_events) && ~isempty(sorted_events.t)
                    % 约束 accumTime 范围（与原逻辑一致 1ms~20ms）
                    if accumTime < 0.001
                        accumTime = 0.001;
                    elseif accumTime > 0.02
                        accumTime = 0.02;
                    end
        
                    % 直接把 UIAxes 作为目标 axes 传进去
                    visualize_event_overlay_formalapp(app.UIAxes, app.VideoData, app.SortedEvents, app.SeqPath, accumTime);%, struct('pause_s',0, 'showTitle',true, 'clearEachFrame', true)
                end
        
                % ------- 可选：在界面上做一些文本反馈 -------
                % 例如：app.StatusLabel.Text = sprintf('Done. Events: %d', numel(sorted_events.t));
        
            catch ME
                % 统一错误弹窗
                if isprop(app, 'UIFigure') && isvalid(app.UIFigure)
                    uialert(app.UIFigure, ME.message, 'Processing Error');
                else
                    disp(getReport(ME));
                end
            end


            % % 修改版：修复参数解析问题
            % 
            % fprintf('=== Video Event Processing (Fixed Version) ===\n');
            % 
            % % 创建 inputParser 对象
            % p = inputParser;
            % 
            % % 添加必需参数
            % addRequired(p, 'videoPath');
            % addRequired(p, 'outputPath');
            % 
            % % 添加参数时使用正确的语法
            % addParameter(p, 'fps', 1000);
            % addParameter(p, 'resizeTo', [540 1024]);
            % addParameter(p, 'C', 0.5);
            % addParameter(p, 'refractory_us', 1);
            % addParameter(p, 'tau_dark_us', 5000);
            % addParameter(p, 'tau_bright_us', 500);
            % addParameter(p, 'enablePR', true);
            % addParameter(p, 'enableLeak', false);
            % addParameter(p, 'enableJitter', false);
            % addParameter(p, 'accumTime', 0.01);
            % addParameter(p, 'showVisualization', true);
            % addParameter(p, 'id', []);
            % 
            % % 解析参数
            % parse(p, videoPath, outputPath, varargin{:});
            % 
            % % 获取解析后的参数
            % videoPath = p.Results.videoPath;
            % outputPath = p.Results.outputPath;
            % fps = p.Results.fps;
            % resizeTo = p.Results.resizeTo;
            % C = p.Results.C;
            % refractory_us = p.Results.refractory_us;
            % tau_dark_us = p.Results.tau_dark_us;
            % tau_bright_us = p.Results.tau_bright_us;
            % enablePR = p.Results.enablePR;
            % enableLeak = p.Results.enableLeak;
            % enableJitter = p.Results.enableJitter;
            % accumTime = p.Results.accumTime;
            % showVisualization = p.Results.showVisualization;
            % id_data = p.Results.id;
            % 
            % % 处理 videoPath 参数
            % if ~isempty(id_data) && isstruct(id_data)
            %     % 如果直接传入了 id 结构体，使用它
            %     id = id_data;
            %     video_source = 'preloaded_data';
            %     fprintf('Using pre-loaded video data\n');
            % else
            %     % 处理文件路径
            %     if ischar(videoPath)
            %         video_source = videoPath;
            %     elseif isstring(videoPath)
            %         video_source = char(videoPath);
            %     else
            %         try
            %             video_source = char(videoPath);
            %         catch
            %             error('Invalid videoPath parameter');
            %         end
            %     end
            %     fprintf('Loading video from: %s\n', video_source);
            % end
            % 
            % % 处理 outputPath
            % if ischar(outputPath)
            %     output_base = outputPath;
            % elseif isstring(outputPath)
            %     output_base = char(outputPath);
            % else
            %     output_base = 'output_events';
            %     fprintf('Using default output name: %s\n', output_base);
            % end
            % 
            % %% ---- 加载视频 ----
            % if ~exist('id', 'var') || isempty(id)
            %     try
            %         fprintf('Loading video data...\n');
            %         id = load_x4k_frames(video_source, fps, resizeTo);
            %     catch ME
            %         fprintf('Error loading video: %s\n', ME.message);
            %         rethrow(ME);
            %     end
            % end
            % 
            % Y = id.Y;
            % t_us = id.t_us;
            % H = id.H;
            % W = id.W;
            % N = id.N;
            % 
            % fprintf('Video loaded: %dx%d, %d frames\n', W, H, N);
            % 
            % %% ---- 配置参数 ----
            % opts = struct();
            % opts.Lref0 = [];
            % opts.refractory_us = refractory_us;
            % opts.threshold_jitter = 0;
            % opts.threshold_sigma_px = 0;
            % 
            % opts.pr.enable = enablePR;
            % opts.pr.tau_dark_us = tau_dark_us;
            % opts.pr.tau_bright_us = tau_bright_us;
            % 
            % opts.leak.enable = enableLeak;
            % opts.timing_jitter.enable = enableJitter;
            % 
            % fprintf('Configuration: C=%.2f, refractory=%dμs\n', C, opts.refractory_us);
            % 
            % %% ---- 并行处理整个图像 ----
            % fprintf('\n=== Starting Parallel Processing ===\n');
            % 
            % event_cells = cell(H, 1);
            % start_time = tic;
            % 
            % try
            %     parfor y = 1:H
            %         row_events = struct('t', [], 'x', [], 'y', [], 'p', []);
            % 
            %         for x = 1:W
            %             L = squeeze(Y(y, x, :));
            %             L_log = log(L + 1e-3);
            % 
            %             [t_events, p_events] = pixel_events_pro_fast(L_log, t_us, C, opts, L);
            % 
            %             if ~isempty(t_events)
            %                 row_events.t = [row_events.t; double(t_events)];
            %                 row_events.x = [row_events.x; repmat(x, length(t_events), 1)];
            %                 row_events.y = [row_events.y; repmat(y, length(t_events), 1)];
            %                 row_events.p = [row_events.p; p_events];
            %             end
            %         end
            % 
            %         event_cells{y} = row_events;
            % 
            %         if mod(y, 50) == 0
            %             fprintf('Processed row %d/%d\n', y, H);
            %         end
            %     end
            % catch ME
            %     fprintf('Error in parallel processing: %s\n', ME.message);
            %     rethrow(ME);
            % end
            % 
            % %% ---- 合并所有行的事件 ----
            % fprintf('Merging events from all rows...\n');
            % 
            % all_events = struct('t', [], 'x', [], 'y', [], 'p', []);
            % 
            % for y = 1:H
            %     if ~isempty(event_cells{y}) && ~isempty(event_cells{y}.t)
            %         all_events.t = [all_events.t; event_cells{y}.t];
            %         all_events.x = [all_events.x; event_cells{y}.x];
            %         all_events.y = [all_events.y; event_cells{y}.y];
            %         all_events.p = [all_events.p; event_cells{y}.p];
            %     end
            % end
            % 
            % processing_time = toc(start_time);
            % fprintf('Processing completed in %.1f seconds\n', processing_time);
            % 
            % %% ---- 结果分析和保存 ----
            % fprintf('\n=== Results Analysis ===\n');
            % 
            % total_events = length(all_events.t);
            % fprintf('Total events generated: %d\n', total_events);
            % 
            % if total_events > 0
            %     fprintf('Average events per pixel: %.3f\n', total_events / (W*H));
            % 
            %     % 排序事件
            %     [sorted_t, sort_idx] = sort(all_events.t);
            %     sorted_events = struct(...
            %         't', sorted_t, ...
            %         'x', all_events.x(sort_idx), ...
            %         'y', all_events.y(sort_idx), ...
            %         'p', all_events.p(sort_idx) ...
            %         );
            % 
            %     % 计算事件率
            %     event_rate = total_events / (double(t_us(end)) * 1e-6);
            %     fprintf('Event rate: %.0f events/second\n', event_rate);
            % 
            %     % 事件时间统计
            %     if length(sorted_events.t) > 1
            %         event_intervals = diff(sorted_events.t);
            %         fprintf('Event interval statistics:\n');
            %         fprintf('  Average interval: %.1f μs\n', mean(event_intervals));
            %         fprintf('  Minimum interval: %.1f μs\n', min(event_intervals));
            %         fprintf('  Maximum interval: %.1f μs\n', max(event_intervals));
            %     end
            % 
            %     % 保存结果
            %     [output_dir, output_name, ~] = fileparts(output_base);
            %     if isempty(output_dir)
            %         output_dir = '.';
            %     end
            % 
            %     mat_output = fullfile(output_dir, [output_name '_events.mat']);
            %     save(mat_output, 'sorted_events', 'H', 'W', 't_us', 'C', 'opts', '-v7.3');
            %     fprintf('Results saved to %s\n', mat_output);
            % 
            %     % 可视化结果
            %     if showVisualization && accumTime > 0
            %         if accumTime < 0.001
            %             fprintf('Accumulation time too small. Using minimum = 0.001 s (1 ms)\n');
            %             accumTime = 0.001;
            %         elseif accumTime > 0.02
            %             fprintf('Accumulation time too large. Using maximum = 0.02 s (20 ms)\n');
            %             accumTime = 0.02;
            %         end
            % 
            %         fprintf('Generating visualization with accumulation time: %.3f s\n', accumTime);
            %         try
            %             % 直接调用可视化函数，传递正确的参数
            %             visualize_event_overlay_simple(app,id, sorted_events, video_source, accumTime);
            %         catch ME
            %             fprintf('Visualization failed: %s\n', ME.message);
            %         end
            %     end
            % 
            %     % 导出CSV
            %     csv_output = fullfile(output_dir, [output_name '_events.csv']);
            %     exportEventsToCSV(app, sorted_events, csv_output, min(1000, total_events));
            % 
            % else
            %     fprintf('No events generated. Consider reducing threshold C.\n');
            % end
            % 
            % %% ---- 性能统计 ----
            % fprintf('\n=== Performance Statistics ===\n');
            % fprintf('Total pixels processed: %d\n', W*H);
            % fprintf('Total processing time: %.1f seconds\n', processing_time);
            % fprintf('Processing speed: %.1f pixels/second\n', (W*H)/processing_time);
            % 
            % if total_events > 0
            %     fprintf('Event generation rate: %.1f events/second\n', total_events/processing_time);
            % end
            % 
            % fprintf('\n=== PROCESSING COMPLETE ===\n');
            % 
            % % 返回结果
            % if nargout > 0
            %     varargout{1} = sorted_events;
            % end
        end

        function visualize_event_overlay_simple(app, id, sorted_events, video_source, accum_time)
            % 简化的可视化函数

            fprintf('Creating event overlay visualization...\n');

            H = id.H;
            W = id.W;
            Y = id.Y;
            t_us = id.t_us;

            % 创建图形窗口
            figure('Position', [100,100,1000,600], 'Name', 'Event Overlay');

            % 选择一些代表性的帧进行显示
            num_frames_to_show = min(6, size(Y,3));
            frame_indices = round(linspace(1, size(Y,3), num_frames_to_show));

            for i = 1:num_frames_to_show
                frame_idx = frame_indices(i);
                t_current = t_us(frame_idx) * 1e-6;

                subplot(2, 3, i);

                % 显示原始帧
                frame = Y(:, :, frame_idx);
                imshow(frame, []);
                hold on;

                % 选择时间窗口内的事件
                t_start = t_current - accum_time/2;
                t_end = t_current + accum_time/2;

                idx = (double(sorted_events.t)*1e-6 >= t_start) & ...
                    (double(sorted_events.t)*1e-6 < t_end);

                evs_x = sorted_events.x(idx);
                evs_y = sorted_events.y(idx);
                evs_p = sorted_events.p(idx);

                % 绘制事件
                onIdx = evs_p == 1;
                offIdx = evs_p == -1;

                if any(onIdx)
                    plot(evs_x(onIdx), evs_y(onIdx), 'r.', 'MarkerSize', 4);
                end
                if any(offIdx)
                    plot(evs_x(offIdx), evs_y(offIdx), 'b.', 'MarkerSize', 4);
                end

                title(sprintf('Frame %d\nTime: %.1f ms', frame_idx, accum_time*1000));
                hold off;
            end

            sgtitle(sprintf('Event Overlay Visualization (Accumulation: %.0f ms)', accum_time*1000));
        end

        function exportEventsToCSV(~, events, filename, num_events)
            % 导出事件数据到CSV文件

            fprintf('Exporting event data to %s...\n', filename);

            try
                fid = fopen(filename, 'w');
                if fid == -1
                    error('Cannot open file for writing: %s', filename);
                end

                fprintf(fid, 'timestamp_us,x,y,polarity\n');

                num_export = min(num_events, length(events.t));
                for i = 1:num_export
                    fprintf(fid, '%.1f,%d,%d,%d\n', ...
                        events.t(i), events.x(i), events.y(i), events.p(i));
                end
                fclose(fid);

                fprintf('Event sample exported to %s (%d events)\n', filename, num_export);
            catch ME
                fprintf('Error exporting CSV: %s\n', ME.message);
            end
        end

        function generateSummaryReport(app)
            if isfield(app.eventData, 'total_events') && app.eventData.total_events > 0
                app.addLog('=== SUMMARY REPORT ===');
                app.addLog('Event Camera Array Construction Complete');
                app.addLog('-----------------------------------------');
                app.addLog(sprintf('Image Resolution: %d x %d pixels', app.eventData.W, app.eventData.H));
                app.addLog(sprintf('Total Pixels: %d', app.eventData.W * app.eventData.H));
                app.addLog(sprintf('Total Events Generated: %d', app.eventData.total_events));
                app.addLog(sprintf('Average Events per Pixel: %.3f', app.eventData.total_events/(app.eventData.W * app.eventData.H)));
                app.addLog(sprintf('Overall Event Rate: %.0f events/second', app.eventData.event_rate));
                app.addLog(sprintf('Processing Time: %.1f seconds', app.eventData.processing_time));
                app.addLog(sprintf('Configuration: C=%.2f, refractory=%dμs', app.eventData.C, app.eventData.opts.refractory_us));

                app.addLog('Event Statistics:');
                app.addLog(sprintf('  ON Events: %d (%.1f%%)', ...
                    app.eventData.polarity_counts(1), ...
                    100*app.eventData.polarity_counts(1)/app.eventData.total_events));
                app.addLog(sprintf('  OFF Events: %d (%.1f%%)', ...
                    app.eventData.polarity_counts(2), ...
                    100*app.eventData.polarity_counts(2)/app.eventData.total_events));

                app.addLog('=== PROCESSING COMPLETE ===');
            else
                app.addLog('=== SUMMARY REPORT ===');
                app.addLog('Processing Completed - No Events Generated');
                app.addLog('Consider adjusting parameters:');
                app.addLog('  - Reduce contrast threshold C');
                app.addLog('  - Increase accumulation time');
                app.addLog('  - Check video content for sufficient motion');
            end
        end

        function exportEventSample(app, csvPath)
            if isfield(app.eventData, 'all_events')
                events = app.eventData.all_events;

                if ~isempty(events.t)
                    [sorted_t, sort_idx] = sort(events.t);
                    sorted_events = struct(...
                        't', sorted_t, ...
                        'x', events.x(sort_idx), ...
                        'y', events.y(sort_idx), ...
                        'p', events.p(sort_idx) ...
                        );

                    num_export = min(1000, length(sorted_events.t));
                    fid = fopen(csvPath, 'w');
                    fprintf(fid, 'timestamp_us,x,y,polarity\n');

                    for i = 1:num_export
                        fprintf(fid, '%.1f,%d,%d,%d\n', ...
                            sorted_events.t(i), sorted_events.x(i), sorted_events.y(i), sorted_events.p(i));
                    end
                    fclose(fid);

                    app.addLog(sprintf('Exported %d events to CSV', num_export));
                end
            end
        end

        function visualizeResults(app)
            if isfield(app.eventData, 'sorted_events') && ~isempty(app.eventData.sorted_events.t)
                events = app.eventData.sorted_events;
                H = app.eventData.H;
                W = app.eventData.W;

                fig = figure('Name', 'Event Visualization', 'NumberTitle', 'off', ...
                    'Position', [100, 100, 1200, 800]);

                subplot(2, 2, 1);
                event_density = accumarray([events.y, events.x], 1, [H, W]);
                imagesc(event_density);
                colorbar;
                title('Spatial Event Density');
                axis image;
                colormap hot;

                subplot(2, 2, 2);
                histogram(events.t * 1e-6, 50);
                xlabel('Time (seconds)');
                ylabel('Number of Events');
                title('Temporal Event Distribution');
                grid on;

                subplot(2, 2, 3);
                polarity_counts = [sum(events.p == 1), sum(events.p == -1)];
                pie(polarity_counts, {'ON Events', 'OFF Events'});
                title(sprintf('Polarity Distribution\nON: %d, OFF: %d', polarity_counts(1), polarity_counts(2)));

                subplot(2, 2, 4);
                event_intervals = diff(events.t);
                histogram(event_intervals, 50);
                xlabel('Event Interval (μs)');
                ylabel('Frequency');
                title('Event Interval Distribution');
                grid on;

                app.addLog('Visualization opened in new window.');

            else
                cla(app.ResultsAxes);
                text(app.ResultsAxes, 0.5, 0.5, 'No event data available for visualization', ...
                    'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
                    'FontSize', 12);
                title(app.ResultsAxes, 'No Data Available');
                app.addLog('No events to visualize.');
            end
            app.ExportButton.Enable = 'on';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseVideoButton
        function ChooseVideoButtonPushed(app, event)
            % 打开文件选择对话框
            appFolder = fileparts(mfilename('fullpath'));   % 获取当前 App 文件所在路径
            defaultPath = fullfile(appFolder, '../test_video/Type2');      % 拼接子目录
            [file, path] = uigetfile({'*.mp4;*.avi;*.mov', 'Video Files (*.mp4, *.avi, *.mov)'}, ...
                'Select Video File',defaultPath);
            if isequal(file, 0)
                return; % 用户取消选择
            end

            fullPath = fullfile(path, file);
            app.VideoPathEditField.Value = fullPath;
            app.addLog(['Selected video file: ' file]);

            % 自动获取视频尺寸并填充
            app.autoFillVideoDimensions(fullPath);
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)

            % 获取视频路径
            videoPath = app.VideoPathEditField.Value;  % 根据你的实际控件名调整

            % 验证视频文件是否存在
            if ~exist(videoPath, 'file')
                app.addLog('ERROR: Video file does not exist!');
                return;
            end

            % 设置UI状态
            app.StatusLamp.Color = 'yellow';
            % app.ProcessButton.Enable = 'off';
            app.addLog('Starting video processing...');

            try
                % 获取参数值
                C = str2double(app.TriggeringthresholdC0105EditField.Value);
                accumulationTime = str2double(app.customizedaccumulationtimesec0001sEditField.Value);
                refractoryTime = str2double(app.RefractoryTimes010EditField.Value);
                resizeWidth = str2double(app.ResizeWidthpixelEditField.Value);
                resizeHeight = str2double(app.ResizeHeightpixelEditField.Value);

                % 参数验证
                if isnan(C) || C <= 0
                    error('Invalid C value');
                end
                if isnan(accumulationTime) || accumulationTime <= 0
                    error('Invalid accumulation time value');
                end
                if isnan(refractoryTime) || refractoryTime < 0
                    error('Invalid refractory time value');
                end

                % 设置输出文件名（基于输入视频名）
                [~, videoName, ~] = fileparts(videoPath);
                outputName = [videoName '_events'];

                % 调用 processVideo 函数
                processVideo(app, videoPath, outputName, ...
                    'C', C, ...
                    'accumTime', accumulationTime, ...
                    'refractory_us', refractoryTime, ...
                    'resizeTo', [resizeHeight, resizeWidth], ...
                    'fps', 1000, ...  % 你可以根据需要添加fps控件或使用默认值
                    'showVisualization', true);  % 在GUI中显示结果

                % 更新UI状态
                app.StatusLamp.Color = 'green';
                app.isProcessed = true;
                app.ExportButton.Enable = 'on';
                app.VisualizeButton.Enable = 'on';
                app.addLog('Processing completed successfully!');

            catch ME
                % 错误处理
                app.StatusLamp.Color = 'red';
                app.addLog(['ERROR: ' ME.message]);
                app.ProcessButton.Enable = 'on';
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if ~app.isProcessed
                app.addLog('ERROR: No processed data to export!');
                return;
            end

            try
                [file, path] = uiputfile('*.mat', 'Save Event Data As');
                if isequal(file, 0)
                    return;
                end

                fullPath = fullfile(path, file);

                eventData = app.eventData;
                save(fullPath, 'eventData', '-v7.3');

                app.addLog(['Event data saved to: ' fullPath]);

                csvPath = strrep(fullPath, '.mat', '_sample.csv');
                app.exportEventSample(csvPath);

            catch ME
                app.addLog(['Export ERROR: ' ME.message]);
            end
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            if ~app.isProcessed
                app.addLog('ERROR: No processed data to visualize!');
                return;
            end

            try
                app.visualizeResults();
                app.addLog('Visualization completed!');
            catch ME
                app.addLog(['Visualization ERROR: ' ME.message]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'VideoAxes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChooseVideoButton = uibutton(app.UIFigure, 'push');
            app.ChooseVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseVideoButtonPushed, true);
            app.ChooseVideoButton.Text = 'Choose Video';
            app.VideoPathLabel = uilabel(app.UIFigure);
            app.VideoPathLabel.Text = 'Video Path';
            app.VideoPathEditField = uieditfield(app.UIFigure, 'text');
            app.Step3CLabel = uilabel(app.UIFigure);
            app.Step3CLabel.Text = {' Triggering threshold C'; '(0.1~0.5)'};
            app.TriggeringthresholdC0105EditField = uieditfield(app.UIFigure, 'text');
            app.TriggeringthresholdC0105EditField.Tooltip = {'Each event is triggered when the log intensity change at a pixel '; 'exceeds this threshold (default 0.5).'};
            app.TriggeringthresholdC0105EditField.Value = '0.5';
            app.Step4AccumulationTimeLabel = uilabel(app.UIFigure);
            app.Step4AccumulationTimeLabel.Text = {'customized accumulation time (sec)'; '（<0.001s）'};
            app.customizedaccumulationtimesec0001sEditField = uieditfield(app.UIFigure, 'text');
            app.customizedaccumulationtimesec0001sEditField.Tooltip = {'Maximum window to integrate log-intensity changes before triggering an '; 'event. Longer windows reduce noise but lower temporal resolution. (default 1/fps = '; '0.001)'};
            app.customizedaccumulationtimesec0001sEditField.Value = '0.001';
            app.RefractoryTimes010EditFieldLabel = uilabel(app.UIFigure);
            app.RefractoryTimes010EditFieldLabel.Text = {' Refractory Time(µs) '; '(0~10)'};
            app.RefractoryTimes010EditField = uieditfield(app.UIFigure, 'text');
            app.RefractoryTimes010EditField.Tooltip = {'Refractory period, events closer than this will be suppressed (default 0).'};
            app.RefractoryTimes010EditField.Value = '0';
            app.VideoWidthLabel = uilabel(app.UIFigure);
            app.VideoWidthLabel.Text = ' Resize Width (pixel)';
            app.ResizeWidthpixelEditField = uieditfield(app.UIFigure, 'text');
            app.ResizeWidthpixelEditField.Tooltip = {'Original video width (no resize) or target spatial resolution (auto setup '; 'after loading video)'};
            app.VideoHeightLabel = uilabel(app.UIFigure);
            app.VideoHeightLabel.Text = ' Resize Height (pixel)';
            app.ResizeHeightpixelEditField = uieditfield(app.UIFigure, 'text');
            app.ResizeHeightpixelEditField.Tooltip = {'Original video height (no resize) or target spatial resolution (auto setup '; 'after loading video)'};
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Tooltip = {'Export the event file in .mat format'};
            app.ExportButton.Text = 'Export';
            app.VisualizeButton = uibutton(app.UIFigure, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Enable = 'off';
            app.VisualizeButton.Text = 'Visualize';
            app.informationboardTextAreaLabel = uilabel(app.UIFigure);
            app.informationboardTextAreaLabel.Text = 'information board';
            app.informationboardTextArea = uitextarea(app.UIFigure);
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'StatusLamp';
            app.StatusLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EventCameraArrayGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end